classdef app21 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel2                 matlab.ui.container.Panel
        ExitButton             matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        UploadButton           matlab.ui.control.Button
        TabGroup               matlab.ui.container.TabGroup
        BasicTab               matlab.ui.container.Tab
        TransformationPanel    matlab.ui.container.Panel
        RotateSlider           matlab.ui.control.Slider
        RotateSliderLabel      matlab.ui.control.Label
        FlipVerticalButton     matlab.ui.control.Button
        FlipHorizontalButton   matlab.ui.control.Button
        RotateButton           matlab.ui.control.Button
        ColorPanel             matlab.ui.container.Panel
        NegativeButton         matlab.ui.control.Button
        BlueButton             matlab.ui.control.Button
        GreenButton            matlab.ui.control.Button
        RedButton              matlab.ui.control.Button
        BlackWhiteButton       matlab.ui.control.Button
        GrayscaleButton        matlab.ui.control.Button
        AdvanceTab             matlab.ui.container.Tab
        GaussianNoiseButton    matlab.ui.control.Button
        SaltPepperNoiseButton  matlab.ui.control.Button
        Tab3                   matlab.ui.container.Tab
        DisplayPanel           matlab.ui.container.Panel
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            global x;
            global a;
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            a = imread(fullname);
            x = a;
            imshow(x, 'parent',app.UIAxes);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global a;
            imshow(a,'parent',app.UIAxes2);
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            global x;
            g = im2gray(x);
            imshow(g, 'parent', app.UIAxes2);
        end

        % Button pushed function: BlackWhiteButton
        function BlackWhiteButtonPushed(app, event)
            global x;
            bw = im2bw(x);
            imshow(bw, 'parent', app.UIAxes2);
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            global x;
            red=x;
            red(:,:,2:3)=0;
            imshow(red, 'parent', app.UIAxes2);
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            global x;
            green=x;
            green(:,:,1)=0;
            green(:,:,3)=0;
            imshow(green, 'parent', app.UIAxes2);
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            global x;
            blue=x;
            blue(:,:,1)=0;
            blue(:,:,2)=0;
            imshow(blue, 'parent', app.UIAxes2);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            global x;
           
            I=rgb2gray(x);
            BW2=edge(I,'canny');
            
            imshow(BW2,'parent',app.UIAxes2);
        end

        % Button pushed function: FlipHorizontalButton
        function FlipHorizontalButtonPushed(app, event)
            global x;
            x = flip(x,2);
            imshow(x, 'parent', app.UIAxes2);
        end

        % Button pushed function: FlipVerticalButton
        function FlipVerticalButtonPushed(app, event)
            global x;

            x = flip(x,1);
            imshow(x, 'parent', app.UIAxes2);
        end

        % Button pushed function: NegativeButton
        function NegativeButtonPushed(app, event)
            global x;
            
            L = 2 ^ 8;
            neg = (L - 1) - x;
            imshow(neg, 'parent', app.UIAxes2);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            newAxes = axes;

            copyobj(app.UIAxes2.Children, newAxes);
            fig2Save = ancestor(newAxes, 'figure');
            fig2Save.Visible='off';

            filter = {'*.jpg';'*.png'};
            [filename, pathname] = uiputfile(filter);
            newfilename = fullfile(pathname, filename);
            saveas(fig2Save, newfilename);
        end

        % Value changed function: RotateSlider
        function RotateSliderValueChanged(app, event)
            global x;
            value = app.RotateSlider.Value;
            J = imrotate(x,value,'bilinear','crop');
            imshow(J,'parent',app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.UIAxes = uiaxes(app.DisplayPanel);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.DisplayPanel);
            title(app.UIAxes2, 'Modified Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.ColorPanel = uipanel(app.BasicTab);
            app.ColorPanel.Title = 'Color';
            app.GrayscaleButton = uibutton(app.ColorPanel, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.BlackWhiteButton = uibutton(app.ColorPanel, 'push');
            app.BlackWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @BlackWhiteButtonPushed, true);
            app.BlackWhiteButton.Text = 'Black & White';
            app.RedButton = uibutton(app.ColorPanel, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.ColorPanel, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uibutton(app.ColorPanel, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Text = 'Blue';
            app.NegativeButton = uibutton(app.ColorPanel, 'push');
            app.NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeButtonPushed, true);
            app.NegativeButton.Text = 'Negative';
            app.TransformationPanel = uipanel(app.BasicTab);
            app.TransformationPanel.Title = 'Transformation';
            app.RotateButton = uibutton(app.TransformationPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.FlipHorizontalButton = uibutton(app.TransformationPanel, 'push');
            app.FlipHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontalButtonPushed, true);
            app.FlipHorizontalButton.Text = 'Flip (Horizontal)';
            app.FlipVerticalButton = uibutton(app.TransformationPanel, 'push');
            app.FlipVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticalButtonPushed, true);
            app.FlipVerticalButton.Text = 'Flip (Vertical)';
            app.RotateSliderLabel = uilabel(app.TransformationPanel);
            app.RotateSliderLabel.Text = 'Rotate';
            app.RotateSlider = uislider(app.TransformationPanel);
            app.RotateSlider.Limits = [0 360];
            app.RotateSlider.ValueChangedFcn = createCallbackFcn(app, @RotateSliderValueChanged, true);
            app.AdvanceTab = uitab(app.TabGroup);
            app.AdvanceTab.Title = 'Advance';
            app.SaltPepperNoiseButton = uibutton(app.AdvanceTab, 'push');
            app.SaltPepperNoiseButton.Text = 'Salt & Pepper Noise';
            app.GaussianNoiseButton = uibutton(app.AdvanceTab, 'push');
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UploadButton = uibutton(app.Panel2, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ResetButton = uibutton(app.Panel2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.Panel2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.Panel2, 'push');
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app21

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end